classdef Proyecto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ArchivoTab                     matlab.ui.container.Tab
        ReproduciroriginalButton_4     matlab.ui.control.Button
        CargarArchivoButton            matlab.ui.control.Button
        Canal1Tab                      matlab.ui.container.Tab
        DistorcinCheckBox              matlab.ui.control.CheckBox
        VibratoCheckBox                matlab.ui.control.CheckBox
        EchoDelayCheckBox              matlab.ui.control.CheckBox
        ReverberacinCheckBox           matlab.ui.control.CheckBox
        PhaserCheckBox                 matlab.ui.control.CheckBox
        FrecuenciadecentroEditFieldLabel  matlab.ui.control.Label
        FrecuenciadecentroEditField    matlab.ui.control.NumericEditField
        FactordecalidadEditField_4Label  matlab.ui.control.Label
        FactordecalidadEditField_4     matlab.ui.control.NumericEditField
        FrecuenciasdecorteEditFieldLabel  matlab.ui.control.Label
        FrecuenciasdecorteEditField    matlab.ui.control.NumericEditField
        ReproduciroriginalButton       matlab.ui.control.Button
        ReproducirnuevoButton          matlab.ui.control.Button
        GananciaSliderLabel            matlab.ui.control.Label
        GananciaSlider                 matlab.ui.control.Slider
        FiltroDropDownLabel            matlab.ui.control.Label
        FiltroDropDown                 matlab.ui.control.DropDown
        ProbarefectosButton            matlab.ui.control.Button
        msdeatrasoEditFieldLabel       matlab.ui.control.Label
        msdeatrasoEditField            matlab.ui.control.NumericEditField
        Canal2Tab                      matlab.ui.container.Tab
        DistorcinCheckBox_2            matlab.ui.control.CheckBox
        VibratoCheckBox_2              matlab.ui.control.CheckBox
        EchoDelayCheckBox_2            matlab.ui.control.CheckBox
        ReverberacinCheckBox_2         matlab.ui.control.CheckBox
        PhaserCheckBox_2               matlab.ui.control.CheckBox
        FrecuenciadecentroEditField_2Label  matlab.ui.control.Label
        FrecuenciadecentroEditField_2  matlab.ui.control.NumericEditField
        FactordecalidadEditField_5Label  matlab.ui.control.Label
        FactordecalidadEditField_5     matlab.ui.control.NumericEditField
        FrecuenciasdecorteEditField_2Label  matlab.ui.control.Label
        FrecuenciasdecorteEditField_2  matlab.ui.control.NumericEditField
        ReproduciroriginalButton_2     matlab.ui.control.Button
        ReproducirnuevoButton_2        matlab.ui.control.Button
        GananciaSlider_2Label          matlab.ui.control.Label
        GananciaSlider_2               matlab.ui.control.Slider
        FiltroDropDown_2Label          matlab.ui.control.Label
        FiltroDropDown_2               matlab.ui.control.DropDown
        ProbarefectosButton_2          matlab.ui.control.Button
        msdeatrasoEditField_2Label     matlab.ui.control.Label
        msdeatrasoEditField_2          matlab.ui.control.NumericEditField
        GrficasfiltroTab               matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        ReproduciroriginalButton_3     matlab.ui.control.Button
        ReproducirnuevoButton_3        matlab.ui.control.Button
        GrficasdeaudioTab              matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        ReproduciroriginalButton_5     matlab.ui.control.Button
        ReproducirnuevoButton_4        matlab.ui.control.Button
        GuardarTab                     matlab.ui.container.Tab
        ReproduciroriginalButton_6     matlab.ui.control.Button
        ReproducirnuevoButton_5        matlab.ui.control.Button
        GuardarnuevocomowavButton      matlab.ui.control.Button
        GuardarvariablescomomatButton  matlab.ui.control.Button
    end

    
    methods (Access = public)
        
        %Función delay
        function [y2, t2]= delay_funcion(x, delay_ms, alfa, Fs)
            %Tiempo de muestreo
            ts = 1/Fs;
            
            %Calculo del numero de muestras que ocupa el delay
            delay_muestra = round((delay_ms/1000)*Fs);
            %Es importante que el tipo de dato de esta operación sea entero
            
            %tamaño de salida de la señal (numero de filas del vector columna)
            N = size(x,1);
        
            %reservar el espacio de memoria (vector columna)
            y2 = zeros(N,1);
            
            %Ecuación de Diferencias
            %Aplicamos la ecuación de diferencias
            for i = 1:N
                if i>delay_muestra
                    y2(i) = x(i) + alfa*x(i-delay_muestra);
                end
                if i<=delay_muestra
                    y2(i) = x(i);
                end
        
            end
        
            %pasamos de muestras a tiempo
            t2 = ts*(0:(N-1));
            t2 = t2.'; %Cambiamos de vector fila a vector columna
        
            %Salidas
            %y2 es la señal procesada
            %t2 es el vector de tiempo para graficar la señal (s)
            
            %Entradas
            %x es la señal de entrada
            %delay_ms es el delay en milisegundos
            %alfa es la ganacia del efecto
            %Fs es la frecuencia de muestreo
            
        end


    end
    

    methods (Access = private)

        % Button pushed function: CargarArchivoButton
        function CargarArchivoButtonPushed(app, event)
            global file;
            global file_path;
            global fs;
            global og;
            %abrimos el archivo y se carga a la app
            [file, file_path] = uigetfile('*.wav');
            %luego de cargar el audio lo guardamos en la memoria
            [og,fs]= audioread(file);
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
        end

        % Button pushed function: ReproduciroriginalButton, 
        % ReproduciroriginalButton_2, ReproduciroriginalButton_3, 
        % ReproduciroriginalButton_4, ReproduciroriginalButton_5, 
        % ReproduciroriginalButton_6
        function ReproduciroriginalButtonPushed(app, event)
            global file;
            global fs;
            global og;            
            soundsc(og, fs);
        end

        % Value changed function: FrecuenciadecentroEditField
        function FrecuenciadecentroEditFieldValueChanged(app, event)
            global frecentro;
            frecentro = app.FrecuenciadecentroEditField.Value;
            
        end

        % Value changed function: FactordecalidadEditField_4
        function FactordecalidadEditField_4ValueChanged(app, event)
            global calidad;
            calidad = app.FactordecalidadEditField_4.Value;
            
        end

        % Value changed function: FrecuenciasdecorteEditField
        function FrecuenciasdecorteEditFieldValueChanged(app, event)
            global frecorte;
            frecorte = app.FrecuenciasdecorteEditField.Value;
           
        end

        % Value changed function: FrecuenciadecentroEditField_2
        function FrecuenciadecentroEditField_2ValueChanged(app, event)
            global frecentro2;
            frecentro2 = app.FrecuenciadecentroEditField_2.Value;
        end

        % Value changed function: FactordecalidadEditField_5
        function FactordecalidadEditField_5ValueChanged(app, event)
            global calidad2;
            calidad2 = app.FactordecalidadEditField_5.Value;
            
        end

        % Value changed function: FrecuenciasdecorteEditField_2
        function FrecuenciasdecorteEditField_2ValueChanged(app, event)
            global frecorte2;
            frecorte2 = app.FrecuenciasdecorteEditField_2.Value;
            
        end

        % Value changed function: GananciaSlider
        function GananciaSliderValueChanged(app, event)
            global ganancia;
            ganancia = app.GananciaSlider.Value;
            
        end

        % Value changed function: GananciaSlider_2
        function GananciaSlider_2ValueChanged(app, event)
            global ganancia2;
            ganancia2= app.GananciaSlider_2.Value;
            
        end

        % Value changed function: DistorcinCheckBox
        function DistorcinCheckBoxValueChanged(app, event)
            %Filtro distorción 1
            %Cada uno de estos filtros hace lo suyo, y genera un yd1, yv1 y asi
            %respectivamente. Realizar la suma ys que se generan 
            global fs;
            global og;
            value = app.DistorcinCheckBox.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
            
            
        end

        % Value changed function: VibratoCheckBox
        function VibratoCheckBoxValueChanged(app, event)
            %Filtro vibrato 1
            global fs;
            global og;
            value = app.VibratoCheckBox.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
            
        end

        % Value changed function: ReverberacinCheckBox
        function ReverberacinCheckBoxValueChanged(app, event)
            %Filtro reverberacion 1
            global fs;
            global og;
            value = app.ReverberacinCheckBox.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
            
        end

        % Value changed function: PhaserCheckBox
        function PhaserCheckBoxValueChanged(app, event)
            %Filtro phaser 1
            global fs;
            global og;
            value = app.PhaserCheckBox.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
        end

        % Value changed function: EchoDelayCheckBox, 
        % msdeatrasoEditField
        function EchoDelayCheckBoxValueChanged(app, event)
            %Filtro echo 1
            global fs;
            global og;
            global delay_out;
            global delay_time_out;
            value = app.EchoDelayCheckBox.Value;
            delay_ms = app.msdeatrasoEditField.Value;
            ganancia_delay = 0.5;
            if (value==1)
                [delay_out, delay_time_out] = delay_funcion(og, delay_ms, ganancia_delay, fs);
            end
        end

        % Value changed function: DistorcinCheckBox_2
        function DistorcinCheckBox_2ValueChanged(app, event)
            %Filtro distorcion 2
            global fs;
            global og;
            value = app.DistorcinCheckBox_2.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
        end

        % Value changed function: VibratoCheckBox_2
        function VibratoCheckBox_2ValueChanged(app, event)
            %Filtro vibrato 2
            global fs;
            global og;
            value = app.VibratoCheckBox_2.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
        end

        % Value changed function: ReverberacinCheckBox_2
        function ReverberacinCheckBox_2ValueChanged(app, event)
            %Filtro reverberacion 2
            global fs;
            global og;
            value = app.ReverberacinCheckBox_2.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
        end

        % Value changed function: PhaserCheckBox_2
        function PhaserCheckBox_2ValueChanged(app, event)
            %Filtro phaser 2
            global fs;
            global og;
            value = app.PhaserCheckBox_2.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
        end

        % Value changed function: EchoDelayCheckBox_2
        function EchoDelayCheckBox_2ValueChanged(app, event)
            %Filtro echo 2
            global fs;
            global og;
            value = app.EchoDelayCheckBox_2.Value;
            if (value==1)
                if (tipo_parametros=='Freq. centro/ Factor calidad')
                    
                end
                if (tipo_parametros=='Freq. corte')
                    
                end
            end
        end

        % Button pushed function: ProbarefectosButton
        function ProbarefectosButtonPushed(app, event)
            global og;
            global fs;
            global delay_out;
            global delay_time_out;
            %Probar efectos 1
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
            %%prueba del delay
            soundsc(delay_out, fs);
        end

        % Button pushed function: ProbarefectosButton_2
        function ProbarefectosButton_2Pushed(app, event)
            %Probar efectos 2
            %Sumar los ys de los efectos seleccionados y reproducir audio
            %del canal 1 modificado
        end

        % Callback function
        function UIAxes3ButtonDown(app, event)
            global og
            plot(og);
            title('Audio original');
            xlabel('Magnitud');
            ylabel('Tiempo (s)');
        end

        % Value changed function: FiltroDropDown
        function FiltroDropDownValueChanged(app, event)
            global tipo_parametros1;
            tipo_parametros1 = app.FiltroDropDown.Value;  
            
        end

        % Value changed function: FiltroDropDown_2
        function FiltroDropDown_2ValueChanged(app, event)
            global tipo_parametros2;
            tipo_parametros2 = app.FiltroDropDown_2.Value;  
        end

        % Callback function
        function UIAxes4ButtonDown(app, event)
            global og
            plot(og)
            title('Audio modificado');
            xlabel('Magnitud');
            ylabel('Tiempo (s)');
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            global og
            plot(og)
            title('Filtro canal 1');
            xlabel('Magnitud');
            ylabel('Tiempo (s)');
        end

        % Callback function
        function UIAxes2ButtonDown(app, event)
            global og
            plot(og)
            title('Filtro canal 2');
            xlabel('Magnitud');
            ylabel('Tiempo (s)');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ArchivoTab = uitab(app.TabGroup);
            app.ArchivoTab.Title = 'Archivo';
            app.ReproduciroriginalButton_4 = uibutton(app.ArchivoTab, 'push');
            app.ReproduciroriginalButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_4.Text = 'Reproducir original';
            app.CargarArchivoButton = uibutton(app.ArchivoTab, 'push');
            app.CargarArchivoButton.ButtonPushedFcn = createCallbackFcn(app, @CargarArchivoButtonPushed, true);
            app.CargarArchivoButton.Text = 'Cargar Archivo';
            app.Canal1Tab = uitab(app.TabGroup);
            app.Canal1Tab.Title = 'Canal 1';
            app.DistorcinCheckBox = uicheckbox(app.Canal1Tab);
            app.DistorcinCheckBox.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBoxValueChanged, true);
            app.DistorcinCheckBox.Text = 'Distorción';
            app.VibratoCheckBox = uicheckbox(app.Canal1Tab);
            app.VibratoCheckBox.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBoxValueChanged, true);
            app.VibratoCheckBox.Text = 'Vibrato';
            app.EchoDelayCheckBox = uicheckbox(app.Canal1Tab);
            app.EchoDelayCheckBox.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBoxValueChanged, true);
            app.EchoDelayCheckBox.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox = uicheckbox(app.Canal1Tab);
            app.ReverberacinCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBoxValueChanged, true);
            app.ReverberacinCheckBox.Text = 'Reverberación';
            app.PhaserCheckBox = uicheckbox(app.Canal1Tab);
            app.PhaserCheckBox.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBoxValueChanged, true);
            app.PhaserCheckBox.Text = 'Phaser ';
            app.FrecuenciadecentroEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciadecentroEditFieldLabel.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciadecentroEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditFieldValueChanged, true);
            app.FactordecalidadEditField_4Label = uilabel(app.Canal1Tab);
            app.FactordecalidadEditField_4Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_4 = uieditfield(app.Canal1Tab, 'numeric');
            app.FactordecalidadEditField_4.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_4ValueChanged, true);
            app.FrecuenciasdecorteEditFieldLabel = uilabel(app.Canal1Tab);
            app.FrecuenciasdecorteEditFieldLabel.Text = 'Frecuencias de corte';
            app.FrecuenciasdecorteEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.FrecuenciasdecorteEditField.ValueChangedFcn = createCallbackFcn(app, @FrecuenciasdecorteEditFieldValueChanged, true);
            app.ReproduciroriginalButton = uibutton(app.Canal1Tab, 'push');
            app.ReproduciroriginalButton.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton.Text = 'Reproducir original';
            app.ReproducirnuevoButton = uibutton(app.Canal1Tab, 'push');
            app.ReproducirnuevoButton.Text = 'Reproducir nuevo';
            app.GananciaSliderLabel = uilabel(app.Canal1Tab);
            app.GananciaSliderLabel.Text = 'Ganancia';
            app.GananciaSlider = uislider(app.Canal1Tab);
            app.GananciaSlider.Limits = [0 1];
            app.GananciaSlider.ValueChangedFcn = createCallbackFcn(app, @GananciaSliderValueChanged, true);
            app.FiltroDropDownLabel = uilabel(app.Canal1Tab);
            app.FiltroDropDownLabel.Text = 'Filtro';
            app.FiltroDropDown = uidropdown(app.Canal1Tab);
            app.FiltroDropDown.Items = {'FIR', 'IIR'};
            app.FiltroDropDown.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDownValueChanged, true);
            app.FiltroDropDown.Value = 'FIR';
            app.ProbarefectosButton = uibutton(app.Canal1Tab, 'push');
            app.ProbarefectosButton.ButtonPushedFcn = createCallbackFcn(app, @ProbarefectosButtonPushed, true);
            app.ProbarefectosButton.Text = 'Probar efectos';
            app.msdeatrasoEditFieldLabel = uilabel(app.Canal1Tab);
            app.msdeatrasoEditFieldLabel.Text = 'ms de atraso';
            app.msdeatrasoEditField = uieditfield(app.Canal1Tab, 'numeric');
            app.msdeatrasoEditField.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBoxValueChanged, true);
            app.Canal2Tab = uitab(app.TabGroup);
            app.Canal2Tab.Title = 'Canal 2';
            app.DistorcinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.DistorcinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @DistorcinCheckBox_2ValueChanged, true);
            app.DistorcinCheckBox_2.Text = 'Distorción';
            app.VibratoCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.VibratoCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @VibratoCheckBox_2ValueChanged, true);
            app.VibratoCheckBox_2.Text = 'Vibrato';
            app.EchoDelayCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.EchoDelayCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @EchoDelayCheckBox_2ValueChanged, true);
            app.EchoDelayCheckBox_2.Text = 'Echo (Delay)';
            app.ReverberacinCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.ReverberacinCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @ReverberacinCheckBox_2ValueChanged, true);
            app.ReverberacinCheckBox_2.Text = 'Reverberación';
            app.PhaserCheckBox_2 = uicheckbox(app.Canal2Tab);
            app.PhaserCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @PhaserCheckBox_2ValueChanged, true);
            app.PhaserCheckBox_2.Text = 'Phaser ';
            app.FrecuenciadecentroEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciadecentroEditField_2Label.Text = 'Frecuencia de centro ';
            app.FrecuenciadecentroEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciadecentroEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciadecentroEditField_2ValueChanged, true);
            app.FactordecalidadEditField_5Label = uilabel(app.Canal2Tab);
            app.FactordecalidadEditField_5Label.Text = 'Factor de calidad';
            app.FactordecalidadEditField_5 = uieditfield(app.Canal2Tab, 'numeric');
            app.FactordecalidadEditField_5.ValueChangedFcn = createCallbackFcn(app, @FactordecalidadEditField_5ValueChanged, true);
            app.FrecuenciasdecorteEditField_2Label = uilabel(app.Canal2Tab);
            app.FrecuenciasdecorteEditField_2Label.Text = 'Frecuencias de corte';
            app.FrecuenciasdecorteEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.FrecuenciasdecorteEditField_2.ValueChangedFcn = createCallbackFcn(app, @FrecuenciasdecorteEditField_2ValueChanged, true);
            app.ReproduciroriginalButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproduciroriginalButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_2.Text = 'Reproducir original';
            app.ReproducirnuevoButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ReproducirnuevoButton_2.Text = 'Reproducir nuevo';
            app.GananciaSlider_2Label = uilabel(app.Canal2Tab);
            app.GananciaSlider_2Label.Text = 'Ganancia';
            app.GananciaSlider_2 = uislider(app.Canal2Tab);
            app.GananciaSlider_2.Limits = [0 1];
            app.GananciaSlider_2.ValueChangedFcn = createCallbackFcn(app, @GananciaSlider_2ValueChanged, true);
            app.FiltroDropDown_2Label = uilabel(app.Canal2Tab);
            app.FiltroDropDown_2Label.Text = 'Filtro ';
            app.FiltroDropDown_2 = uidropdown(app.Canal2Tab);
            app.FiltroDropDown_2.Items = {'FIR', 'IIR'};
            app.FiltroDropDown_2.ValueChangedFcn = createCallbackFcn(app, @FiltroDropDown_2ValueChanged, true);
            app.FiltroDropDown_2.Value = 'FIR';
            app.ProbarefectosButton_2 = uibutton(app.Canal2Tab, 'push');
            app.ProbarefectosButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProbarefectosButton_2Pushed, true);
            app.ProbarefectosButton_2.Text = 'Probar efectos';
            app.msdeatrasoEditField_2Label = uilabel(app.Canal2Tab);
            app.msdeatrasoEditField_2Label.Text = 'ms de atraso';
            app.msdeatrasoEditField_2 = uieditfield(app.Canal2Tab, 'numeric');
            app.GrficasfiltroTab = uitab(app.TabGroup);
            app.GrficasfiltroTab.Title = 'Gráficas filtro';
            app.UIAxes = uiaxes(app.GrficasfiltroTab);
            title(app.UIAxes, 'Filtro canal 1')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GrficasfiltroTab);
            title(app.UIAxes2, 'Filtro canal 2')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.ReproduciroriginalButton_3 = uibutton(app.GrficasfiltroTab, 'push');
            app.ReproduciroriginalButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_3.Text = 'Reproducir original';
            app.ReproducirnuevoButton_3 = uibutton(app.GrficasfiltroTab, 'push');
            app.ReproducirnuevoButton_3.Text = 'Reproducir nuevo';
            app.GrficasdeaudioTab = uitab(app.TabGroup);
            app.GrficasdeaudioTab.Title = 'Gráficas de audio';
            app.UIAxes3 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes3, 'Audio original')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GrficasdeaudioTab);
            title(app.UIAxes4, 'Audio modificado')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.ReproduciroriginalButton_5 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproduciroriginalButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_5.Text = 'Reproducir original';
            app.ReproducirnuevoButton_4 = uibutton(app.GrficasdeaudioTab, 'push');
            app.ReproducirnuevoButton_4.Text = 'Reproducir nuevo';
            app.GuardarTab = uitab(app.TabGroup);
            app.GuardarTab.Title = 'Guardar ';
            app.ReproduciroriginalButton_6 = uibutton(app.GuardarTab, 'push');
            app.ReproduciroriginalButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReproduciroriginalButtonPushed, true);
            app.ReproduciroriginalButton_6.Text = 'Reproducir original';
            app.ReproducirnuevoButton_5 = uibutton(app.GuardarTab, 'push');
            app.ReproducirnuevoButton_5.Text = 'Reproducir nuevo';
            app.GuardarnuevocomowavButton = uibutton(app.GuardarTab, 'push');
            app.GuardarnuevocomowavButton.Text = 'Guardar nuevo como .wav';
            app.GuardarvariablescomomatButton = uibutton(app.GuardarTab, 'push');
            app.GuardarvariablescomomatButton.Text = 'Guardar variables como .mat';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Proyecto

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end